classdef version_file_change < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        version_file_changeUIFigure     matlab.ui.Figure
        ParLat_Version_MajorEditFieldLabel  matlab.ui.control.Label
        ParLat_Version_MajorEditField   matlab.ui.control.NumericEditField
        Abbrivation2digitsEditFieldLabel  matlab.ui.control.Label
        Abbrivation2digitsEditField     matlab.ui.control.EditField
        GetActVersionButton             matlab.ui.control.Button
        SetNewVersionButton             matlab.ui.control.Button
        NoChangeButton                  matlab.ui.control.Button
        AdaptVersionNumberLabel         matlab.ui.control.Label
        CommentEditFieldLabel           matlab.ui.control.Label
        CommentEditField                matlab.ui.control.EditField
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        ParLat_Version_MinorEditFieldLabel  matlab.ui.control.Label
        ParLat_Version_MinorEditField   matlab.ui.control.NumericEditField
        ParLat_Version_BuildEditFieldLabel  matlab.ui.control.Label
        ParLat_Version_BuildEditField   matlab.ui.control.NumericEditField
        ParLong_Version_MajorEditFieldLabel  matlab.ui.control.Label
        ParLong_Version_MajorEditField  matlab.ui.control.NumericEditField
        ParLong_Version_MinorEditFieldLabel  matlab.ui.control.Label
        ParLong_Version_MinorEditField  matlab.ui.control.NumericEditField
        ParLong_Version_BuildEditFieldLabel  matlab.ui.control.Label
        ParLong_Version_BuildEditField  matlab.ui.control.NumericEditField
    end

  
  properties (Access = private)
      
    VersionFileName = ''; % VersionFileName
    
    LongLatComment = '';
    ParLong_Version_MMB                         % build + minor*1000 + major*100000
    ParLong_Set_Version = 0;
    ParLong_Version_Build                       % Long Version Build-Number 0-15
    ParLong_Version_Minor                       % Long Version Minor-Number 0-31
    ParLong_Version_Major                       % Long Version Major-Number 0-127
    
    ParLat_Set_Version = 0;
    ParLat_Version_MMB                          % build + minor*1000 + major*100000
    ParLat_Version_Build                        % Lat Version Build-Number 0-15
    ParLat_Version_Minor                        % Lat Version Minor-Number 0-31
    ParLat_Version_Major                        % Lat Version Major-Number 0-127
    
  end
    
    methods (Access = private)
        
        function GetVersion(app)
            
            % read version File
            s = version_file_read(app.VersionFileName);
            
            if( ~s.okay )
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,s.errText);
            else
                
                app.LongLatComment = s.Comment;
            
                if( isfield(s,'ParLat_Version_Major') && isfield(s,'ParLat_Version_Minor') && isfield(s,'ParLat_Version_Build') )
                    
                    app.ParLat_Set_Version = 1;
                    app.ParLat_Version_Major = s.ParLat_Version_Major;
                    app.ParLat_Version_Minor = s.ParLat_Version_Minor;
                    app.ParLat_Version_Build = s.ParLat_Version_Build;
                    app.ParLat_Version_MMB   = app.ParLat_Version_Build+1000*(app.ParLat_Version_Minor+app.ParLat_Version_Major*100);
                    
                    app.ParLat_Version_MajorEditField.Value = double(app.ParLat_Version_Major);
                    app.ParLat_Version_MinorEditField.Value = double(app.ParLat_Version_Minor);
                    app.ParLat_Version_BuildEditField.Value = double(app.ParLat_Version_Build);
                else
                   app.ParLat_Set_Version = 0; 
                    app.ParLat_Version_MajorEditField.Value = 999;
                    app.ParLat_Version_MinorEditField.Value = 999;
                    app.ParLat_Version_BuildEditField.Value = 999;
                end
                
                
                if( isfield(s,'ParLong_Version_Major') && isfield(s,'ParLong_Version_Minor') && isfield(s,'ParLong_Version_Build') )
                    
                    app.ParLong_Set_Version = 1;
                    app.ParLong_Version_Major = s.ParLong_Version_Major;
                    app.ParLong_Version_Minor = s.ParLong_Version_Minor;
                    app.ParLong_Version_Build = s.ParLong_Version_Build;
                    app.ParLong_Version_MMB   = app.ParLong_Version_Build+1000*(app.ParLong_Version_Minor+app.ParLong_Version_Major*100);
                    
                    app.ParLong_Version_MajorEditField.Value = double(app.ParLong_Version_Major);
                    app.ParLong_Version_MinorEditField.Value = double(app.ParLong_Version_Minor);
                    app.ParLong_Version_BuildEditField.Value = double(app.ParLong_Version_Build);
                else
                   app.ParLong_Set_Version = 0; 
                    app.ParLong_Version_MajorEditField.Value = 999;
                    app.ParLong_Version_MinorEditField.Value = 999;
                    app.ParLong_Version_BuildEditField.Value = 999;
    
                end
            end
            
        end
    end
  

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ProjectName)
            
            % Check ProjectName
            %------------------
            if( exist('ProjectName','var') )
                % Build VerionFileNumber
                %-----------------------
                app.VersionFileName = [ProjectName,'_model_version.m'];
                % Log
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Read VersionFileName: %s',app.VersionFileName));
                
                % Read Version File                
                runStartupFcn(app, @(app)GetVersion(app))

                % Log
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Last Comment:'));
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('%s',app.LongLatComment{end}));
            else
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,'Error: No ProjectName set  (start with versionChange(ProjectName)');
            end


        end

        % Button pushed function: GetActVersionButton
        function GetActVersionButtonPushed(app, event)
      
            if( ~isempty(app.VersionFileName) )
                
                % Read Version File
                runStartupFcn(app, @(app)GetVersion(app))
                % Log
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Last Comment:'));
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('%s',app.LongLatComment{end}));
            else
                % error
                app.LogTextArea.Value = cell_add(app.LogTextArea.Value,'Error: No VersionFileName set (ProjectName set)');                
            end
      
        end

        % Button pushed function: SetNewVersionButton
        function SetNewVersionButtonPushed(app, event)
            
           flag_save_new = 0;
           
           %
           % check for Long-Versionnumber Change
           %            
           if( app.ParLong_Set_Version )
               
               ma = min(max(floor(app.ParLong_Version_MajorEditField.Value),0),15);
               mi = min(max(floor(app.ParLong_Version_MinorEditField.Value),0),31);
               bu = min(max(floor(app.ParLong_Version_BuildEditField.Value),0),127);
               mmb_long_s = sprintf('%i.%i.%i',uint32(ma),uint32(mi),uint32(bu));
               mmb_long   = bu+1000*(mi+ma*100);
                
               if( mmb_long < app.ParLong_Version_MMB  )
                    % error
                    oldval = sprintf('%i.%i.%i',uint32(app.ParLong_Version_Major),uint32(app.ParLong_Version_Minor),uint32(app.ParLong_Version_Build));
                    
                    app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Error: New Long-Versionnumber (%s) is higher than old Long-Verionnumber (%s)',mmb_long_s,oldval));
                    return
               end
               
               if( mmb_long > app.ParLong_Version_MMB )
                  app.ParLong_Version_MajorEditField.Value = double(ma);
                  app.ParLong_Version_MinorEditField.Value = double(mi);
                  app.ParLong_Version_BuildEditField.Value = double(bu);
                  flag_save_new = 1;
               end
                
           end
           %
           % check for Lat-Versionnumber Change
           %            
           if( app.ParLat_Set_Version )
               
               ma = min(max(floor(app.ParLat_Version_MajorEditField.Value),0),15);
               mi = min(max(floor(app.ParLat_Version_MinorEditField.Value),0),31);
               bu = min(max(floor(app.ParLat_Version_BuildEditField.Value),0),127);
               mmb_lat_s = sprintf('%i.%i.%i',uint32(ma),uint32(mi),uint32(bu));
               mmb_lat   = bu+1000*(mi+ma*100);
                
               if( mmb_lat < app.ParLat_Version_MMB  )
                    % error
                    oldval = sprintf('%i.%i.%i',uint32(app.ParLat_Version_Major),uint32(app.ParLat_Version_Minor),uint32(app.ParLat_Version_Build));
                    
                    app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Error: New Lat-Versionnumber (%s) is higher than old Lat-Verionnumber (%s)',mmb_lat_s,oldval));
                    return
               end
               
               if( mmb_lat > app.ParLat_Version_MMB )
                  app.ParLat_Version_MajorEditField.Value = double(ma);
                  app.ParLat_Version_MinorEditField.Value = double(mi);
                  app.ParLat_Version_BuildEditField.Value = double(bu);
                  flag_save_new = 1;
               end
                
           end


           %
           % check comment and abbrivation if flag_save_new
           %
           if( flag_save_new )
               
               % Abbrivation
               %
               if( isempty(app.Abbrivation2digitsEditField.Value) )
                   app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Error: Field Abbrivation with 2 digits is empty !!'));
                   return
               else
                   if(length(app.Abbrivation2digitsEditField.Value) > 5 )
                        app.Abbrivation2digitsEditField.Value = app.Abbrivation2digitsEditField.Value(1:5);
                   end
               end
               
               % Comment
               %
               if( isempty(app.CommentEditField.Value) )
                   app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Error: Field Comment is empty !!'));
                   return
               end
               
               % Date
               %
               d = datestr(now,'dd.mm.yyyy');
               
               % build comment line for m-File
               %
               tt=sprintf('%% Lat %s Long %s: %5s, %s     %s',mmb_lat_s,mmb_long_s,app.Abbrivation2digitsEditField.Value,d,app.CommentEditField.Value);
               
               app.LongLatComment = cell_add(app.LongLatComment,tt);
               
               % write version File
               s.Comment = app.LongLatComment;
               s.ParLat_Version_Major = uint32(app.ParLat_Version_MajorEditField.Value);
               s.ParLat_Version_Minor = uint32(app.ParLat_Version_MinorEditField.Value);
               s.ParLat_Version_Build = uint32(app.ParLat_Version_BuildEditField.Value);
               s.ParLong_Version_Major = uint32(app.ParLong_Version_MajorEditField.Value);
               s.ParLong_Version_Minor = uint32(app.ParLong_Version_MinorEditField.Value);
               s.ParLong_Version_Build = uint32(app.ParLong_Version_BuildEditField.Value);

               [okay,errText] = version_file_write(app.VersionFileName,s);
               
               if( ~okay )
                   app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Error: %s',errText));
                   return
               end   
               
               % Ende
               %
               runStartupFcn(app, @(app)delete(app))

           else
               app.LogTextArea.Value = cell_add(app.LogTextArea.Value,sprintf('Error: Lat-Versionnumber (%s) and Long-Verionnumber (%s) has not be changed',mmb_lat_s,mmb_long_s));
           end
           
            
        end

        % Button pushed function: NoChangeButton
        function NoChangeButtonPushed(app, event)
             
            runStartupFcn(app, @(app)delete(app))
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.version_file_changeUIFigure = uifigure;
            app.version_file_changeUIFigure.Name = 'version_file_change';
            app.ParLat_Version_MajorEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.ParLat_Version_MajorEditFieldLabel.Text = 'ParLat_Version_Major';
            app.ParLat_Version_MajorEditField = uieditfield(app.version_file_changeUIFigure, 'numeric');
            app.Abbrivation2digitsEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.Abbrivation2digitsEditFieldLabel.Text = 'Abbrivation (2 digits)';
            app.Abbrivation2digitsEditField = uieditfield(app.version_file_changeUIFigure, 'text');
            app.GetActVersionButton = uibutton(app.version_file_changeUIFigure, 'push');
            app.GetActVersionButton.ButtonPushedFcn = createCallbackFcn(app, @GetActVersionButtonPushed, true);
            app.GetActVersionButton.Text = 'GetActVersion';
            app.SetNewVersionButton = uibutton(app.version_file_changeUIFigure, 'push');
            app.SetNewVersionButton.ButtonPushedFcn = createCallbackFcn(app, @SetNewVersionButtonPushed, true);
            app.SetNewVersionButton.Text = 'SetNewVersion';
            app.NoChangeButton = uibutton(app.version_file_changeUIFigure, 'push');
            app.NoChangeButton.ButtonPushedFcn = createCallbackFcn(app, @NoChangeButtonPushed, true);
            app.NoChangeButton.Text = 'NoChange';
            app.AdaptVersionNumberLabel = uilabel(app.version_file_changeUIFigure);
            app.AdaptVersionNumberLabel.Text = 'Adapt Version Number';
            app.CommentEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.version_file_changeUIFigure, 'text');
            app.LogTextAreaLabel = uilabel(app.version_file_changeUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.version_file_changeUIFigure);
            app.ParLat_Version_MinorEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.ParLat_Version_MinorEditFieldLabel.Text = 'ParLat_Version_Minor';
            app.ParLat_Version_MinorEditField = uieditfield(app.version_file_changeUIFigure, 'numeric');
            app.ParLat_Version_BuildEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.ParLat_Version_BuildEditFieldLabel.Text = 'ParLat_Version_Build';
            app.ParLat_Version_BuildEditField = uieditfield(app.version_file_changeUIFigure, 'numeric');
            app.ParLong_Version_MajorEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.ParLong_Version_MajorEditFieldLabel.Text = 'ParLong_Version_Major';
            app.ParLong_Version_MajorEditField = uieditfield(app.version_file_changeUIFigure, 'numeric');
            app.ParLong_Version_MinorEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.ParLong_Version_MinorEditFieldLabel.Text = 'ParLong_Version_Minor';
            app.ParLong_Version_MinorEditField = uieditfield(app.version_file_changeUIFigure, 'numeric');
            app.ParLong_Version_BuildEditFieldLabel = uilabel(app.version_file_changeUIFigure);
            app.ParLong_Version_BuildEditFieldLabel.Text = 'ParLong_Version_Build';
            app.ParLong_Version_BuildEditField = uieditfield(app.version_file_changeUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = version_file_change(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.version_file_changeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.version_file_changeUIFigure)
        end
    end
end